classdef Clustering_app_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClusteringToolUIFigure         matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SettingsPanel                  matlab.ui.container.Panel
        SelectdataButton               matlab.ui.control.Button
        StartEditFieldLabel            matlab.ui.control.Label
        Start_file                     matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        End_file                       matlab.ui.control.NumericEditField
        MEditFieldLabel                matlab.ui.control.Label
        M                              matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        N                              matlab.ui.control.NumericEditField
        MinclusterEditFieldLabel       matlab.ui.control.Label
        Mincluster                     matlab.ui.control.NumericEditField
        MaxclusterEditFieldLabel       matlab.ui.control.Label
        Maxcluster                     matlab.ui.control.NumericEditField
        Measuretype_dropdown           matlab.ui.control.DropDown
        FeaturespacePanel              matlab.ui.container.Panel
        ListBox_feature_space          matlab.ui.control.ListBox
        ClusteringalgorithmPanel       matlab.ui.container.Panel
        ListBox_algo                   matlab.ui.control.ListBox
        RunButton_Featurespace         matlab.ui.control.Button
        RunButton_Clustering           matlab.ui.control.Button
        RunButton_Readdata             matlab.ui.control.Button
        RunButton_Plotting             matlab.ui.control.Button
        FeaturespacedimensionalityreductionPanel  matlab.ui.container.Panel
        ListBox_dim_red                matlab.ui.control.ListBox
        Image_intro                    matlab.ui.control.Image
        RightPanel                     matlab.ui.container.Panel
        Image_empa_logo                matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        AdvancedSettingsTab            matlab.ui.container.Tab
        RegionofinterestPanel          matlab.ui.container.Panel
        ROIXminEditFieldLabel          matlab.ui.control.Label
        ROIXmin                        matlab.ui.control.NumericEditField
        ROIXmaxEditFieldLabel          matlab.ui.control.Label
        ROIXmax                        matlab.ui.control.NumericEditField
        ROIYminEditFieldLabel          matlab.ui.control.Label
        ROIYmin                        matlab.ui.control.NumericEditField
        ROIYmaxEditFieldLabel          matlab.ui.control.Label
        ROIYmax                        matlab.ui.control.NumericEditField
        XscaleDropDownLabel            matlab.ui.control.Label
        XscaleDropDown                 matlab.ui.control.DropDown
        YscaleDropDownLabel            matlab.ui.control.Label
        YscaleDropDown                 matlab.ui.control.DropDown
        PlottingPanel                  matlab.ui.container.Panel
        XmaxLabel                      matlab.ui.control.Label
        PlotXmax                       matlab.ui.control.NumericEditField
        YminLabel                      matlab.ui.control.Label
        PlotYmin                       matlab.ui.control.NumericEditField
        YmaxLabel                      matlab.ui.control.Label
        PlotYmax                       matlab.ui.control.NumericEditField
        YbinsLabel                     matlab.ui.control.Label
        PlotYbins                      matlab.ui.control.NumericEditField
        XbinsLabel                     matlab.ui.control.Label
        PlotXbins                      matlab.ui.control.NumericEditField
        XminLabel                      matlab.ui.control.Label
        PlotXmin                       matlab.ui.control.NumericEditField
        HideplotsCheckBox              matlab.ui.control.CheckBox
        Replot_Button                  matlab.ui.control.Button
        DatainputPanel                 matlab.ui.container.Panel
        HeaderEditFieldLabel           matlab.ui.control.Label
        HeaderEditField                matlab.ui.control.EditField
        XlabelEditFieldLabel           matlab.ui.control.Label
        XlabelEditField                matlab.ui.control.EditField
        YlabelEditFieldLabel           matlab.ui.control.Label
        YlabelEditField                matlab.ui.control.EditField
        FeaturespaceafterdimPanel      matlab.ui.container.Panel
        NormalizationDropDownLabel     matlab.ui.control.Label
        NormalizationDropDown          matlab.ui.control.DropDown
        tSNEUMAPDistanceDropDownLabel  matlab.ui.control.Label
        tSNEUMAPDistanceDropDown       matlab.ui.control.DropDown
        DimredLabel                    matlab.ui.control.Label
        Dimentionalityreduction        matlab.ui.control.NumericEditField
        LemmersreferencePanel          matlab.ui.container.Panel
        FunctionyxEditFieldLabel       matlab.ui.control.Label
        Lemmers_Function               matlab.ui.control.EditField
        distanceDropDownLabel          matlab.ui.control.Label
        distanceDropDown               matlab.ui.control.DropDown
        ParallelcomputingPanel         matlab.ui.container.Panel
        CoresLabel                     matlab.ui.control.Label
        CoresField                     matlab.ui.control.NumericEditField
        Switch_Parellel                matlab.ui.control.Switch
        ASCIIOutputPanel               matlab.ui.container.Panel
        Switch_ASCII                   matlab.ui.control.Switch
        hierchaicalDropDownLabel       matlab.ui.control.Label
        HierchicallinkagePanel         matlab.ui.container.Panel
        hierarchicalDropDown           matlab.ui.control.DropDown
        ListBox_status                 matlab.ui.control.ListBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        
    end
    
    properties (Access = public)
        Data_struct
        Status_string
        string
        N_lines
        file
        FileLoaded
        fileext
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% add paths
            addpath(genpath('Libs'));
            
            %% add images
            Image = imread("Libs\Logo_Empa.png");
            app.Image_empa_logo.ImageSource = Image;
            
            Image = imread("Libs\Intro.png");
            app.Image_intro.ImageSource = Image;
            
            %% get number of core
            numcores = feature('numcores');
            app.CoresField.Value = numcores - 1;
            
            %% reset status window
            reset_status_window(app);
            
        end

        % Button pushed function: SelectdataButton
        function SelectdataButtonPushed(app, event)
            app.Data_struct = [];
            
            % get file
            [app.file, app.Data_struct.filepath] = uigetfile( ...
                {   '*.*',  'All Files (*.*)'}, ...
                'Select a File');
            
            if ~isempty(app.file) && sum(app.file ~= 0)
                [~, ~, app.fileext] = fileparts(app.file);
                
                if isempty(app.fileext)
                    app.fileext = '';
                end
                
                if regexp(app.fileext,'.mat')
                    
                    %                     %% load existing mat file
                    %                     load([app.Data_struct.filepath app.file],'Data')
                    %
                    %                     app.Data_struct = Data;
                    %
                    %                     %% load settings
                    %                     if isfield(Data, 'Feature_spaces')
                    %                         app.RunButton_Readdata.Enable = 1;
                    %                         app.RunButton_Featurespace.Enable = 1;
                    %                         app.RunButton_Clustering.Enable = 1;
                    %                         app.RunButton_Plotting.Enable = 0;
                    %
                    %                         app.ListBox_feature_space.Value = app.Data_struct.Feature_space_selected;
                    %                         app.ListBox_dim_red.Value = app.Data_struct.Dim_reduc_selected;
                    %                     end
                    %                     if isfield(Data, 'Clustering')
                    %                         app.RunButton_Clustering.Enable = 1;
                    %                         app.ListBox_algo.Value = app.Data_struct.clust_algo_selected;
                    %                         app.RunButton_Plotting.Enable = 1;
                    %                     end
                    %
                    %                     %% make plot
                    %                     Make_2D_histo_GUI(app);
                    %
                    %                     %% update status window
                    %                     reset_status_window(app);
                    %                     str = sprintf('%s - %01d measurements loaded\n', app.Data_struct.filename, app.Data_struct.N_measurements);
                    %                     update_status_window(app, str, true);
                    %
                    %                     %% update GUI
                    %                     app.RunButton_Featurespace.Enable = 1;
                    
                else
                    
                    %% Get directory content
                    app = Get_files(app);
                    
                    %% output directory
                    app.Data_struct.output_path = sprintf('%s%s_processed', app.Data_struct.filepath, app.Data_struct.filename);
                    
                    %% update status window
                    reset_status_window(app);
                    str = sprintf('%s - %01d files found\n', app.Data_struct.filename, app.Data_struct.N_files);
                    update_status_window(app, str, true);
                    
                    %% update GUI
                    app.RunButton_Readdata.Enable = 1;
                    
                end
                
                % update interface
                app.FileLoaded = 1;
                app.Start_file.Value = 1;
                app.End_file.Value = app.Data_struct.N_files;
                app.RunButton_Clustering.Enable = 0;
                app.RunButton_Plotting.Enable = 0;
                app.RunButton_Featurespace.Enable = 0;
                
            else
                
                %% update status window
                str = 'No file selected';
                update_status_window(app, str, true);
            end
            
        end

        % Value changed function: End_file
        function End_fileValueChanged(app, event)
            value = app.End_file.Value;
            if value > app.Data_struct.N_files && app.FileLoaded
                errordlg('Error','Index of last file exceeded')
            end
        end

        % Value changed function: Start_file
        function Start_fileValueChanged(app, event)
            value = app.Start_file.Value;
            if value > app.Data_struct.N_files - 1 && app.FileLoaded
                errordlg('Error','Index of first file exceeded')
            end
        end

        % Button pushed function: RunButton_Readdata
        function RunButton_ReaddataPushed(app, event)
            
            %% reset data, feature spaces and clusters
            app.RunButton_Clustering.Enable = 0;
            app.RunButton_Plotting.Enable = 0;
            app.RunButton_Featurespace.Enable = 0;
            
            if isfield(app.Data_struct,'Data')
                app.Data_struct = rmfield(app.Data_struct, 'Data');
            end
            if isfield(app.Data_struct,'Feature_spaces')
                app.Data_struct = rmfield(app.Data_struct, 'Feature_spaces');
            end
            if isfield(app.Data_struct,'Clustering')
                app.Data_struct = rmfield(app.Data_struct, 'Clustering');
            end
            
            %% create output directory
            if ~exist(app.Data_struct.output_path, 'dir')
                mkdir(app.Data_struct.output_path)
            end
            
            %% read data
            app = Read_data(app);
            
            %% update status windows
            str = sprintf('%s%d measurements loaded\n', app.Status_string, app.Data_struct.N_measurements);
            update_status_window(app, str, true);
            
            %% save data
            if ~exist(sprintf('%s/Data/', app.Data_struct.output_path),'dir')
                mkdir(sprintf('%s/Data/', app.Data_struct.output_path))
            end
            
            Data = app.Data_struct;
            save(sprintf('%s/Data/Data.mat', app.Data_struct.output_path), 'Data');
            
            %% update status window
            str = 'Creating 2D histogram';
            update_status_window(app, str, true);
            
            %% Make 2D histogram
            Make_2D_histo_GUI(app);
            
            %% update GUI
            app.RunButton_Featurespace.Enable = 1;
            app.Replot_Button.Enable = 1;
            
        end

        % Button pushed function: RunButton_Featurespace
        function RunButton_FeaturespacePushed(app, event)
            
            %% reset feature spaces and clusters
            app.RunButton_Clustering.Enable = 0;
            app.RunButton_Plotting.Enable = 0;
            
            if isfield(app.Data_struct,'Feature_spaces')
                app.Data_struct = rmfield(app.Data_struct, 'Feature_spaces');
            end
            if isfield(app.Data_struct,'Clustering')
                app.Data_struct = rmfield(app.Data_struct, 'Clustering');
            end
            
            %% get settings
            app.Data_struct.Feature_space_selected = app.ListBox_feature_space.Value;
            app.Data_struct.Dim_reduc_selected = app.ListBox_dim_red.Value;
            app.Data_struct.nDim = app.Dimentionalityreduction.Value;
            
            app.Data_struct.N_feature_space = length(app.Data_struct.Feature_space_selected);
            app.Data_struct.N_dim_reduc = length(app.Data_struct.Dim_reduc_selected);
           
            %% loop through feature spaces
            for i = 1:app.Data_struct.N_feature_space
                
                %% select feature space
                FS = app.Data_struct.Feature_space_selected{i};
                
                %% Get feature space
                Data = GetFeatureSpace(app, FS);
                
                %% loop through dimentionality algorithms
                for j = 1:app.Data_struct.N_dim_reduc
                    
                    %% select dimentionality algorithm
                    DR = app.Data_struct.Dim_reduc_selected{j};
                    feature_space_string = [FS ' + ' DR];
                    
                    %% update status window
                    str = sprintf('Creating feature space %s\n', feature_space_string);
                    update_status_window(app, str, true);
                    
                    %% reduce dimensions
                    data = Reduce_dim(app, DR, Data);
                    
                    %% Normalize feature space
                    [feature_space, feature_space_raw] = Normalize_FeatureSpace(app, data);
                    
                    %% store feature space
                    feature_space_string_reduc = feature_space_string;
                    feature_space_string_reduc(regexp(feature_space_string_reduc, '\W') ) = [];
                    app.Data_struct.Feature_spaces.(feature_space_string_reduc).data = feature_space_raw;
                    app.Data_struct.Feature_spaces.(feature_space_string_reduc).data_raw = feature_space;
                    
                    %% save ASCII
                    if strcmp(app.Switch_ASCII.Value, 'On')
                        if ~exist(sprintf('%s/FeatureSpaces/', app.Data_struct.output_path), 'dir')
                            mkdir(sprintf('%s/FeatureSpaces/', app.Data_struct.output_path))
                        end
                        
                        dlmwrite(sprintf('%s/FeatureSpaces/%s.dat', app.Data_struct.output_path, feature_space_string_reduc), feature_space)
                        
                    end
                    
                end
            end
            
            %% update status window
            str = 'Feature spaces created';
            update_status_window(app, str, true);
            
            %% update GUI
            app.RunButton_Clustering.Enable = 1;
            
            %% save data
            Data = app.Data_struct;
            save(sprintf('%s/Data/Data.mat', app.Data_struct.output_path), 'Data');
            
        end

        % Button pushed function: RunButton_Clustering
        function RunButton_ClusteringPushed(app, event)
            
            %% reset clusters
            app.RunButton_Plotting.Enable = 0;
            if isfield(app.Data_struct,'Clustering')
                app.Data_struct = rmfield(app.Data_struct, 'Clustering');
            end
            
            % get clustering algorithms
            app.Data_struct.clust_algo_selected = app.ListBox_algo.Value;
            app.Data_struct.N_clust_algo_selected = length(app.Data_struct.clust_algo_selected);
            
            % get number of clusters
            app.Data_struct.Clusters = app.Mincluster.Value:app.Maxcluster.Value;
            
            %% loop through feature space and dimentionality reduction
            for i = 1:app.Data_struct.N_feature_space
                for j = 1:app.Data_struct.N_dim_reduc
                    
                    %% load feature space and dimentionality reduction
                    FS = app.Data_struct.Feature_space_selected{i};
                    DR = app.Data_struct.Dim_reduc_selected{j};
                    
                    feature_space_string = [FS ' + ' DR];
                    feature_space_string_reduc = feature_space_string;
                    feature_space_string_reduc(regexp(feature_space_string_reduc, '\W') ) = [];
                    
                    %% loop through clustering algorithms
                    for k = 1:app.Data_struct.N_clust_algo_selected
                        CA = app.Data_struct.clust_algo_selected{k};
                        
                        %% update status window
                        str = sprintf('Clustering %s using %s \n', feature_space_string, CA);
                        update_status_window(app, str, true);
                        
                        %% Perform clustering
                        ClusterX = GetClusters(app, CA, feature_space_string_reduc);
                        
                        %% save cluster output
                        CA_reduc = CA;
                        CA_reduc(regexp(CA_reduc, '\W') ) = [];
                        app.Data_struct.Clustering.(feature_space_string_reduc).(CA_reduc) = ClusterX;
                        
                        %% save ASCII
                        if strcmp(app.Switch_ASCII.Value, 'On')
                            if ~exist(sprintf('%s/Clusters/', app.Data_struct.output_path), 'dir')
                                mkdir(sprintf('%s/Clusters/', app.Data_struct.output_path))
                            end
                            
                            dlmwrite(sprintf('%s/Clusters/%s_%s.dat', app.Data_struct.output_path, feature_space_string_reduc, CA_reduc), ClusterX)
                            
                        end
                        
                    end
                end
                % update settings and interface
                app.RunButton_Plotting.Enable = 1;
                
                %% update status window
                str = 'Clusters created';
                update_status_window(app, str, true);
                
                %% save data
                Data = app.Data_struct;
                save(sprintf('%s/Data/Data.mat', app.Data_struct.output_path), 'Data');
                
            end
            
            
        end

        % Value changed function: Mincluster
        function MinclusterValueChanged(app, event)
            if app.Mincluster.Value <= 2
                app.Mincluster.Value = 2;
            end
        end

        % Button pushed function: RunButton_Plotting
        function RunButton_PlottingPushed(app, event)
            
            %% Create plot directory
            if ~exist(sprintf('%s/Plots/', app.Data_struct.output_path), 'dir')
                mkdir(sprintf('%s/Plots/', app.Data_struct.output_path))
            end
            
            %% make 2D histogram of each trace
            [images, X_array, Y_array] = Make_2D_histo(app);
            
            
            %% loop through plots
            for i = 1:app.Data_struct.N_feature_space
                for j = 1:app.Data_struct.N_dim_reduc
                    
                    %% load feature space
                    FS = app.Data_struct.Feature_space_selected{i};
                    DR = app.Data_struct.Dim_reduc_selected{j};
                    
                    feature_space_string = [FS ' + ' DR];
                    feature_space_string_reduc = feature_space_string;
                    feature_space_string_reduc(regexp(feature_space_string_reduc, '\W') ) = [];
                    
                    for k = 1:app.Data_struct.N_clust_algo_selected
                        
                        %% load clusters
                        CA = app.Data_struct.clust_algo_selected{k};
                        CA_reduc = CA;
                        CA_reduc(regexp(CA_reduc, '\W') ) = [];
                        ClusterX = app.Data_struct.Clustering.(feature_space_string_reduc).(CA_reduc);
                        
                        %% update status window
                        str = sprintf('Plotting %s + %s\n', feature_space_string, CA);
                        update_status_window(app, str, true);
                        
                        %% make plots
                        for l = 1:length(app.Data_struct.Clusters)
                            
                            fig1 = plot_clusters_generic(app, images, X_array, Y_array, ClusterX(:,l));
                            saveas(fig1, sprintf('%s/Plots/N=%d_%s_%s_2DHisto_%02dClusters.png', app.Data_struct.output_path, app.Data_struct.N_measurements, feature_space_string, CA_reduc, app.Data_struct.Clusters(l)))
                            
                            fig2 = plot_feature_space(app, ClusterX(:,l), app.Data_struct.Feature_spaces.(feature_space_string_reduc).data);
                            saveas(fig2, sprintf('%s/Plots/N=%d_%s_%s_Feature_space_%02dClusters.png', app.Data_struct.output_path, app.Data_struct.N_measurements, feature_space_string, CA_reduc, app.Data_struct.Clusters(l)))
                        
                            close all
                        
                        end
                    end
                end
            end
            
            %% update status window
            str = 'Plots created';
            update_status_window(app, str, true);
            
        end

        % Value changed function: Measuretype_dropdown
        function Measuretype_dropdownValueChanged(app, event)
            
            set_settings(app);
            
        end

        % Value changed function: Switch_Parellel
        function Switch_ParellelValueChanged(app, event)
            
            %% Switch on or off parallel computing
            if strcmp(app.Switch_Parellel.Value, 'On')
                N_cores = app.CoresField.Value;
                parpool(N_cores);
                
                %% update status window
                str = sprintf('Parallel pool started on %02d workers', N_cores);
                update_status_window(app, str, true);
                
            end
            if strcmp(app.Switch_Parellel.Value, 'Off')
                delete(gcp('nocreate'));
                
                %% update status window
                str = 'Parallel pool stopped';
                update_status_window(app, str, true);
            end
            
        end

        % Value changed function: CoresField
        function CoresFieldValueChanged(app, event)
            
            if strcmp(app.Switch_Parellel.Value, 'On')
                N_cores = app.CoresField.Value;
                
                %% parrallel execution
                pool = gcp('nocreate');
                if isempty(pool)
                    parpool(N_cores);
                    pool = gcp('nocreate');
                end
                if pool.NumWorkers ~= N_cores
                    delete(gcp('nocreate'));
                    parpool(N_cores);
                end
                
                %% update status window
                str = sprintf('Parallel pool changed on %02d workers', N_cores);
                update_status_window(app, str, true);
                
            end
            
        end

        % Button pushed function: Replot_Button
        function Replot_ButtonPushed(app, event)
            
            close all
            
            %% Make 2D histogram
            Make_2D_histo_GUI(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ClusteringToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {634, 634};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {468, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClusteringToolUIFigure = uifigure('Visible', 'off');
            app.ClusteringToolUIFigure.Name = 'Clustering Tool';
            app.ClusteringToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ClusteringToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.SelectdataButton = uibutton(app.SettingsPanel, 'push');
            app.SelectdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdataButtonPushed, true);
            app.SelectdataButton.Text = 'Select data';
            app.StartEditFieldLabel = uilabel(app.SettingsPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.Start_file = uieditfield(app.SettingsPanel, 'numeric');
            app.Start_file.ValueChangedFcn = createCallbackFcn(app, @Start_fileValueChanged, true);
            app.Start_file.Tooltip = {'If 0 start at first available'; ''};
            app.EndEditFieldLabel = uilabel(app.SettingsPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.End_file = uieditfield(app.SettingsPanel, 'numeric');
            app.End_file.ValueDisplayFormat = '%11.6g';
            app.End_file.ValueChangedFcn = createCallbackFcn(app, @End_fileValueChanged, true);
            app.End_file.Tooltip = {'If 0 start use all available measurements'; ''};
            app.MEditFieldLabel = uilabel(app.SettingsPanel);
            app.MEditFieldLabel.Text = 'M';
            app.M = uieditfield(app.SettingsPanel, 'numeric');
            app.M.Tooltip = {'If 0 start at first available'; ''};
            app.M.Value = 28;
            app.NEditFieldLabel = uilabel(app.SettingsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.N = uieditfield(app.SettingsPanel, 'numeric');
            app.N.Tooltip = {'If 0 start at first available'; ''};
            app.N.Value = 28;
            app.MinclusterEditFieldLabel = uilabel(app.SettingsPanel);
            app.MinclusterEditFieldLabel.Text = 'Min. cluster';
            app.Mincluster = uieditfield(app.SettingsPanel, 'numeric');
            app.Mincluster.ValueChangedFcn = createCallbackFcn(app, @MinclusterValueChanged, true);
            app.Mincluster.Tooltip = {'If 0 start at first available'; ''};
            app.Mincluster.Value = 2;
            app.MaxclusterEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaxclusterEditFieldLabel.Text = 'Max. cluster';
            app.Maxcluster = uieditfield(app.SettingsPanel, 'numeric');
            app.Maxcluster.Tooltip = {'If 0 start at first available'; ''};
            app.Maxcluster.Value = 9;
            app.Measuretype_dropdown = uidropdown(app.SettingsPanel);
            app.Measuretype_dropdown.Items = {'Default', 'MCBJ_Delft', 'MCBJ', 'Raman', 'Custom'};
            app.Measuretype_dropdown.ValueChangedFcn = createCallbackFcn(app, @Measuretype_dropdownValueChanged, true);
            app.Measuretype_dropdown.Value = 'Default';
            app.FeaturespacePanel = uipanel(app.LeftPanel);
            app.FeaturespacePanel.Title = 'Feature space';
            app.ListBox_feature_space = uilistbox(app.FeaturespacePanel);
            app.ListBox_feature_space.Items = {'Raw', 'M x N', 'Lemmers et al.', 'Custom'};
            app.ListBox_feature_space.Multiselect = 'on';
            app.ListBox_feature_space.Value = {'M x N'};
            app.ClusteringalgorithmPanel = uipanel(app.LeftPanel);
            app.ClusteringalgorithmPanel.Title = 'Clustering algorithm';
            app.ListBox_algo = uilistbox(app.ClusteringalgorithmPanel);
            app.ListBox_algo.Items = {'k-means', 'k-mediods', 'Gust. - Kessel', 'Gath - Geva', 'Fuzzy c-means', 'Graph av. link.', 'Graph deg. link.', 'Spectral (S&M)', 'Spectral (J&W)', 'Self. Org. Maps', 'Gauss. Mixt. Mod.', 'OPTICS', 'Hierarchical', 'Density', 'Custom'};
            app.ListBox_algo.Multiselect = 'on';
            app.ListBox_algo.Value = {'Graph av. link.'};
            app.RunButton_Featurespace = uibutton(app.LeftPanel, 'push');
            app.RunButton_Featurespace.ButtonPushedFcn = createCallbackFcn(app, @RunButton_FeaturespacePushed, true);
            app.RunButton_Featurespace.Enable = 'off';
            app.RunButton_Featurespace.Text = 'Create feature space';
            app.RunButton_Clustering = uibutton(app.LeftPanel, 'push');
            app.RunButton_Clustering.ButtonPushedFcn = createCallbackFcn(app, @RunButton_ClusteringPushed, true);
            app.RunButton_Clustering.Enable = 'off';
            app.RunButton_Clustering.Text = 'Run clustering';
            app.RunButton_Readdata = uibutton(app.LeftPanel, 'push');
            app.RunButton_Readdata.ButtonPushedFcn = createCallbackFcn(app, @RunButton_ReaddataPushed, true);
            app.RunButton_Readdata.Enable = 'off';
            app.RunButton_Readdata.Text = 'Import data';
            app.RunButton_Plotting = uibutton(app.LeftPanel, 'push');
            app.RunButton_Plotting.ButtonPushedFcn = createCallbackFcn(app, @RunButton_PlottingPushed, true);
            app.RunButton_Plotting.Enable = 'off';
            app.RunButton_Plotting.Text = 'Plot clusters';
            app.FeaturespacedimensionalityreductionPanel = uipanel(app.LeftPanel);
            app.FeaturespacedimensionalityreductionPanel.Title = 'Feature space dimensionality reduction';
            app.ListBox_dim_red = uilistbox(app.FeaturespacedimensionalityreductionPanel);
            app.ListBox_dim_red.Items = {'None', 'UMAP', 'PCA', 'k-PCA', 'Autoencoder', 'Multi dim. scal.', 'SNE', 'tSNE', 'Sammon', 'Custom'};
            app.ListBox_dim_red.Multiselect = 'on';
            app.ListBox_dim_red.Value = {'UMAP'};
            app.Image_intro = uiimage(app.LeftPanel);
            app.Image_intro.Tag = 'Intro_image';
            app.Image_intro.ImageSource = 'Intro.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_empa_logo = uiimage(app.RightPanel);
            app.Image_empa_logo.ImageSource = 'Logo_Empa.png';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.RegionofinterestPanel = uipanel(app.AdvancedSettingsTab);
            app.RegionofinterestPanel.Title = 'Region of interest';
            app.ROIXminEditFieldLabel = uilabel(app.RegionofinterestPanel);
            app.ROIXminEditFieldLabel.Text = 'ROI Xmin';
            app.ROIXmin = uieditfield(app.RegionofinterestPanel, 'numeric');
            app.ROIXmin.Tooltip = {'If 0 start at first available'; ''};
            app.ROIXmaxEditFieldLabel = uilabel(app.RegionofinterestPanel);
            app.ROIXmaxEditFieldLabel.Text = 'ROI Xmax';
            app.ROIXmax = uieditfield(app.RegionofinterestPanel, 'numeric');
            app.ROIXmax.Tooltip = {'If 0 start at first available'; ''};
            app.ROIXmax.Value = 1;
            app.ROIYminEditFieldLabel = uilabel(app.RegionofinterestPanel);
            app.ROIYminEditFieldLabel.Text = 'ROI Ymin';
            app.ROIYmin = uieditfield(app.RegionofinterestPanel, 'numeric');
            app.ROIYmin.Tooltip = {'If 0 start at first available'; ''};
            app.ROIYmaxEditFieldLabel = uilabel(app.RegionofinterestPanel);
            app.ROIYmaxEditFieldLabel.Text = 'ROI Ymax';
            app.ROIYmax = uieditfield(app.RegionofinterestPanel, 'numeric');
            app.ROIYmax.Tooltip = {'If 0 start at first available'; ''};
            app.ROIYmax.Value = 1;
            app.XscaleDropDownLabel = uilabel(app.RegionofinterestPanel);
            app.XscaleDropDownLabel.Text = 'X scale';
            app.XscaleDropDown = uidropdown(app.RegionofinterestPanel);
            app.XscaleDropDown.Items = {'Linear', 'Log'};
            app.XscaleDropDown.Value = 'Linear';
            app.YscaleDropDownLabel = uilabel(app.RegionofinterestPanel);
            app.YscaleDropDownLabel.Text = 'Y scale';
            app.YscaleDropDown = uidropdown(app.RegionofinterestPanel);
            app.YscaleDropDown.Items = {'Linear', 'Log'};
            app.YscaleDropDown.Value = 'Linear';
            app.PlottingPanel = uipanel(app.AdvancedSettingsTab);
            app.PlottingPanel.Title = 'Plotting';
            app.XmaxLabel = uilabel(app.PlottingPanel);
            app.XmaxLabel.Text = 'Xmax';
            app.PlotXmax = uieditfield(app.PlottingPanel, 'numeric');
            app.PlotXmax.Tooltip = {'If 0 start at first available'; ''};
            app.PlotXmax.Value = 1;
            app.YminLabel = uilabel(app.PlottingPanel);
            app.YminLabel.Text = 'Ymin';
            app.PlotYmin = uieditfield(app.PlottingPanel, 'numeric');
            app.PlotYmin.Tooltip = {'If 0 start at first available'; ''};
            app.YmaxLabel = uilabel(app.PlottingPanel);
            app.YmaxLabel.Text = 'Ymax';
            app.PlotYmax = uieditfield(app.PlottingPanel, 'numeric');
            app.PlotYmax.Tooltip = {'If 0 start at first available'; ''};
            app.PlotYmax.Value = 1;
            app.YbinsLabel = uilabel(app.PlottingPanel);
            app.YbinsLabel.Text = 'Ybins';
            app.PlotYbins = uieditfield(app.PlottingPanel, 'numeric');
            app.PlotYbins.Tooltip = {'If 0 start at first available'; ''};
            app.PlotYbins.Value = 101;
            app.XbinsLabel = uilabel(app.PlottingPanel);
            app.XbinsLabel.Text = 'Xbins';
            app.PlotXbins = uieditfield(app.PlottingPanel, 'numeric');
            app.PlotXbins.Tooltip = {'If 0 start at first available'; ''};
            app.XminLabel = uilabel(app.PlottingPanel);
            app.XminLabel.Text = 'Xmin';
            app.PlotXmin = uieditfield(app.PlottingPanel, 'numeric');
            app.PlotXmin.Tooltip = {'If 0 start at first available'; ''};
            app.HideplotsCheckBox = uicheckbox(app.PlottingPanel);
            app.HideplotsCheckBox.Text = 'Hide plots';
            app.Replot_Button = uibutton(app.PlottingPanel, 'push');
            app.Replot_Button.ButtonPushedFcn = createCallbackFcn(app, @Replot_ButtonPushed, true);
            app.Replot_Button.Enable = 'off';
            app.Replot_Button.Text = 'Replot';
            app.DatainputPanel = uipanel(app.AdvancedSettingsTab);
            app.DatainputPanel.Title = 'Data input';
            app.HeaderEditFieldLabel = uilabel(app.DatainputPanel);
            app.HeaderEditFieldLabel.Text = 'Header';
            app.HeaderEditField = uieditfield(app.DatainputPanel, 'text');
            app.XlabelEditFieldLabel = uilabel(app.DatainputPanel);
            app.XlabelEditFieldLabel.Text = 'X label';
            app.XlabelEditField = uieditfield(app.DatainputPanel, 'text');
            app.XlabelEditField.Value = 'X';
            app.YlabelEditFieldLabel = uilabel(app.DatainputPanel);
            app.YlabelEditFieldLabel.Text = 'Y label';
            app.YlabelEditField = uieditfield(app.DatainputPanel, 'text');
            app.YlabelEditField.Value = 'Y';
            app.FeaturespaceafterdimPanel = uipanel(app.AdvancedSettingsTab);
            app.FeaturespaceafterdimPanel.Title = 'Feature space (after dim.)';
            app.NormalizationDropDownLabel = uilabel(app.FeaturespaceafterdimPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.FeaturespaceafterdimPanel);
            app.NormalizationDropDown.Items = {'None', 'Range', 'Standard deviation'};
            app.NormalizationDropDown.Value = 'Range';
            app.tSNEUMAPDistanceDropDownLabel = uilabel(app.FeaturespaceafterdimPanel);
            app.tSNEUMAPDistanceDropDownLabel.Text = 't-SNE/UMAP Distance';
            app.tSNEUMAPDistanceDropDown = uidropdown(app.FeaturespaceafterdimPanel);
            app.tSNEUMAPDistanceDropDown.Items = {'cosine', 'euclidean', 'seuclidean', 'chebychev', 'cityblock', 'minkowski', 'mahalanobis', 'correlation', 'hamming', 'jaccard'};
            app.tSNEUMAPDistanceDropDown.Value = 'cosine';
            app.DimredLabel = uilabel(app.FeaturespaceafterdimPanel);
            app.DimredLabel.Text = 'Dim. red.';
            app.Dimentionalityreduction = uieditfield(app.FeaturespaceafterdimPanel, 'numeric');
            app.Dimentionalityreduction.Tooltip = {'If 0 start at first available'; ''};
            app.Dimentionalityreduction.Value = 3;
            app.LemmersreferencePanel = uipanel(app.AdvancedSettingsTab);
            app.LemmersreferencePanel.Title = 'Lemmers reference';
            app.FunctionyxEditFieldLabel = uilabel(app.LemmersreferencePanel);
            app.FunctionyxEditFieldLabel.Enable = 'off';
            app.FunctionyxEditFieldLabel.Text = 'Function y(x) = ';
            app.Lemmers_Function = uieditfield(app.LemmersreferencePanel, 'text');
            app.Lemmers_Function.Enable = 'off';
            app.Lemmers_Function.Value = 'x';
            app.distanceDropDownLabel = uilabel(app.LemmersreferencePanel);
            app.distanceDropDownLabel.Enable = 'off';
            app.distanceDropDownLabel.Text = 'distance';
            app.distanceDropDown = uidropdown(app.LemmersreferencePanel);
            app.distanceDropDown.Items = {'Hamming', 'Length'};
            app.distanceDropDown.Enable = 'off';
            app.distanceDropDown.Value = 'Hamming';
            app.ParallelcomputingPanel = uipanel(app.AdvancedSettingsTab);
            app.ParallelcomputingPanel.Title = 'Parallel computing';
            app.CoresLabel = uilabel(app.ParallelcomputingPanel);
            app.CoresLabel.Enable = 'off';
            app.CoresLabel.Text = 'Cores';
            app.CoresField = uieditfield(app.ParallelcomputingPanel, 'numeric');
            app.CoresField.ValueChangedFcn = createCallbackFcn(app, @CoresFieldValueChanged, true);
            app.CoresField.Enable = 'off';
            app.CoresField.Tooltip = {'If 0 start at first available'; ''};
            app.CoresField.Value = 1;
            app.Switch_Parellel = uiswitch(app.ParallelcomputingPanel, 'slider');
            app.Switch_Parellel.ValueChangedFcn = createCallbackFcn(app, @Switch_ParellelValueChanged, true);
            app.Switch_Parellel.Enable = 'off';
            app.ASCIIOutputPanel = uipanel(app.AdvancedSettingsTab);
            app.ASCIIOutputPanel.Title = 'ASCII Output';
            app.Switch_ASCII = uiswitch(app.ASCIIOutputPanel, 'slider');
            app.Switch_ASCII.Value = 'On';
            app.hierchaicalDropDownLabel = uilabel(app.AdvancedSettingsTab);
            app.hierchaicalDropDownLabel.Text = '';
            app.HierchicallinkagePanel = uipanel(app.AdvancedSettingsTab);
            app.HierchicallinkagePanel.Title = 'Hierchical linkage';
            app.hierarchicalDropDown = uidropdown(app.HierchicallinkagePanel);
            app.hierarchicalDropDown.Items = {'average', 'centroid', 'medoid', 'ward', 'weigthed', 'single', 'complete'};
            app.hierarchicalDropDown.Value = 'average';
            app.ListBox_status = uilistbox(app.RightPanel);
            app.ListBox_status.Items = {'Written by Mickael Perrin', '<Mickael.Perrin@empa.ch>'};
            app.ListBox_status.Value = 'Written by Mickael Perrin';
            % Show the figure after all components are created
            app.ClusteringToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clustering_app_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClusteringToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClusteringToolUIFigure)
        end
    end
end